classdef data_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        UIAxes                 matlab.ui.control.UIAxes           % Real-Time
        UIAxes2                matlab.ui.control.UIAxes           % Final
        LabelSwitch            matlab.ui.control.Label            % Switch
        Switch                 matlab.ui.control.Switch           % Stop, S...
        TabGroup               matlab.ui.container.TabGroup       % Input, ...
        Tab                    matlab.ui.container.Tab            % Input
        LabelEditField2        matlab.ui.control.Label            % Name of...
        EditField2             matlab.ui.control.EditField       
        LabelNumericEditField5 matlab.ui.control.Label            % Amount ...
        NumericEditField5      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % Thresho...
        NumericEditField6      matlab.ui.control.NumericEditField % [-Inf Inf]
        Tab2                   matlab.ui.container.Tab            % Output
        LabelNumericEditField  matlab.ui.control.Label            % R^2
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Current...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Time fr...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField3        matlab.ui.control.Label            % Equation
        EditField3             matlab.ui.control.EditField       
        Label                  matlab.ui.control.Label            % Flip to...
        LabelTextArea          matlab.ui.control.Label            % Note an...
        TextArea               matlab.ui.control.TextArea         % -Wear P...
        Label2                 matlab.ui.control.Label            % The tre...
    end

    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function data_processing(app)
            if strcmp(app.Switch.Value,'Start')
                %% Prepping for a new plot
                newplot(app.UIAxes)
                newplot(app.UIAxes2)
                format long
                serialInfo = instrhwinfo('serial');%this code seems to work really well, optimization is needed but very little
                a=serialInfo.AvailableSerialPorts();
                port=serial(a{1});
                fopen(port);
                readasync(port);
                flag=0; % the final flag that stops the loop
                flag1=0; %start counting
                thres=app.NumericEditField6.Value;
                hold(app.UIAxes,'on');
                grid(app.UIAxes,'on');
                d=[];
                counter=0;
                
                %% Real time data logging
                while flag==0
                    a=fscanf(port);
                    b=textscan(a,'%d');
                    d=[d;b{1}(1) b{1}(2)];%build a matrix of data
                    app.NumericEditField2.Value=b{1}(2);
                    plot(app.UIAxes,b{1}(1),b{1}(2),'.k');
                    if b{1}(2)<=thres && length(d(:,1))>=20%the second condition is a precaution against the lack of light inside the cone, add a > when you get a chance
                        flag1=1;
                    end
                    if flag1==1
                        counter=counter+1;
                    end
                    if counter==15
                        flag=1;
                    end
                    pause(280/1000)% change this value to make sure that it's longer than the time it takes to collect a sample
                end
                stopasync(port);
                fclose(port);
                
                %% Putting the max lux on top
                [m,x]=max(d(:,2));%m is max lux, x is the index number of that max
                if(x~=1)
                x=x-1;
                while x~=0
                    d(1,:)=[];
                    x=x-1;
                end
                end
                
                %% Curve fitting as well as plotting
                [eq,go]=fit(d(:,1),d(:,2),'exp1');
                app.NumericEditField.Value=go.rsquare;
                time_from_max=double(d(find(d(:,2)<=thres,1,'first'),1)-d(1,1));
                app.NumericEditField4.Value=time_from_max;
                st=[num2str(eq.a),'*e^(',num2str(eq.b),'*x)'];
                app.EditField3.Value=st;
                plot(app.UIAxes2,d(:,1),d(:,2),'-k')
                hold(app.UIAxes2,'on')
                te=eq.a*exp(eq.b.*double(d(:,1)));
                plot(app.UIAxes2,double(d(:,1)),te,'-r')
                
                %% writing to file
                file_name=[strrep(datestr(now),':','_'),' ',num2str(app.NumericEditField5.Value),' microlitter',' ',app.EditField2.Value,'.xlsm'];
                time=d(:,1);
                lux=d(:,2);
                a=table(time,lux);
                writetable(a,file_name)%this is the almost complete algorithm for creating and writing table, very few changes are needed
                
                %% Cleaning up
                clear flag1 counter port a b d serialInfo flag thres;
                clear eq go time_from_max x st te;
                hold(app.UIAxes,'off');
                hold(app.UIAxes2,'off')
                app.Switch.Value='Stop';
                %winopen(file_name);%uncomment to open excel file when finished.
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            directory=pwd;
            dir=['explorer',' ',directory];
            dos(dir);
            app.NumericEditField6.Value=950;
        end

        % Switch value changed function
        function Main_switch(app)
            data_processing(app)
            
        end

        % NumericEditField value changed function
        function R_2(app)
            value = app.NumericEditField.Value;
            
        end

        % NumericEditField2 value changed function
        function Lux_current(app)
            value = app.NumericEditField2.Value;
            
        end

        % NumericEditField4 value changed function
        function timer(app)
            value = app.NumericEditField4.Value;
            
        end

        % EditField2 value changed function
        function member_name(app)
            value = app.EditField2.Value;
            
        end

        % NumericEditField5 value changed function
        function bleach_used(app)
            value = app.NumericEditField5.Value;
            
        end

        % NumericEditField6 value changed function
        function threshold(app)
            value = app.NumericEditField6.Value;
            
        end

        % EditField3 value changed function
        function equation(app)
            value = app.EditField3.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-Time');
            xlabel(app.UIAxes, 'Time(milli second)');
            ylabel(app.UIAxes, 'Lux');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Final');
            xlabel(app.UIAxes2, 'Time(mili second)');
            ylabel(app.UIAxes2, 'Lux');
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Stop', 'Start'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @Main_switch);
            app.Switch.Value = 'Stop';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Input';
            app.LabelEditField2 = uilabel(app.Tab);
            app.LabelEditField2.Text = 'Name of a member(first)';
            app.EditField2 = uieditfield(app.Tab, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @member_name);
            app.LabelNumericEditField5 = uilabel(app.Tab);
            app.LabelNumericEditField5.Text = 'Amount of Bleach used';
            app.NumericEditField5 = uieditfield(app.Tab, 'numeric');
            app.NumericEditField5.ValueChangedFcn = createCallbackFcn(app, @bleach_used);
            app.LabelNumericEditField6 = uilabel(app.Tab);
            app.LabelNumericEditField6.Text = 'Threshold Lux';
            app.NumericEditField6 = uieditfield(app.Tab, 'numeric');
            app.NumericEditField6.ValueChangedFcn = createCallbackFcn(app, @threshold);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Output';
            app.LabelNumericEditField = uilabel(app.Tab2);
            app.LabelNumericEditField.Text = 'R^2';
            app.NumericEditField = uieditfield(app.Tab2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @R_2);
            app.LabelNumericEditField2 = uilabel(app.Tab2);
            app.LabelNumericEditField2.Text = 'Current Lux';
            app.NumericEditField2 = uieditfield(app.Tab2, 'numeric');
            app.NumericEditField2.ValueChangedFcn = createCallbackFcn(app, @Lux_current);
            app.LabelNumericEditField4 = uilabel(app.Tab2);
            app.LabelNumericEditField4.Text = 'Time from top Lux';
            app.NumericEditField4 = uieditfield(app.Tab2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @timer);
            app.LabelEditField3 = uilabel(app.Tab2);
            app.LabelEditField3.Text = 'Equation';
            app.EditField3 = uieditfield(app.Tab2, 'text');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @equation);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Flip to Start to begin data collecting';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'Note and Safety';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'-Wear PPE and practice safety.'; '-When data collection is done, the switch will be automatically flipped to Stop.'; '-Ask someone if you don''t know how to use this program.'; '-Do not unplug the Arduino between trials. '; '-Output file is accessed on the opened window explorer(Ctr+Shift+E for macro in excel).'; '-Remember to upload the file to the drive in timely manner.'; '-For the sensor: Black to A5, Red to A4, Orange to Ground, White to 5V.'};
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'The trend line is red while the original line is black.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = data_interface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end